classdef GUI_Revised < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ZSlider_2            matlab.ui.control.Slider
        ZSlider_2Label       matlab.ui.control.Label
        YSlider_2            matlab.ui.control.Slider
        YSlider_2Label       matlab.ui.control.Label
        XSlider_2            matlab.ui.control.Slider
        XSlider_2Label       matlab.ui.control.Label
        ZSlider              matlab.ui.control.Slider
        ZSliderLabel         matlab.ui.control.Label
        YSlider              matlab.ui.control.Slider
        YSliderLabel         matlab.ui.control.Label
        XSlider              matlab.ui.control.Slider
        XSliderLabel         matlab.ui.control.Label
        Joint6Slider_R       matlab.ui.control.Slider
        Joint6SliderLabel    matlab.ui.control.Label
        Joint5Slider_R       matlab.ui.control.Slider
        Joint5Slider_2Label  matlab.ui.control.Label
        Joint4Slider_R       matlab.ui.control.Slider
        Joint4Slider_2Label  matlab.ui.control.Label
        Joint3Slider_R       matlab.ui.control.Slider
        Joint3Slider_2Label  matlab.ui.control.Label
        Joint2Slider_R       matlab.ui.control.Slider
        Joint2Slider_2Label  matlab.ui.control.Label
        Joint1Slider_R       matlab.ui.control.Slider
        Joint1Slider_2Label  matlab.ui.control.Label
        RozumPulse75Label    matlab.ui.control.Label
        DobotLabel           matlab.ui.control.Label
        Joint5Slider_D       matlab.ui.control.Slider
        Joint5SliderLabel    matlab.ui.control.Label
        Joint4Slider_D       matlab.ui.control.Slider
        Joint4SliderLabel    matlab.ui.control.Label
        Joint3Slider_D       matlab.ui.control.Slider
        Joint3SliderLabel    matlab.ui.control.Label
        Joint2Slider_D       matlab.ui.control.Slider
        Joint2SliderLabel    matlab.ui.control.Label
        Joint1Slider_D       matlab.ui.control.Slider
        Joint1SliderLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            global r;
            global d; 

            global qd1 qd2 qd3 qd4 qd5
            qd = d.model.getpos;
            qd1 = qd(1);
            qd2 = qd(2);
            qd3 = qd(3);
            qd4 = qd(4);
            qd5 = qd(5);
       
            global qr1 qr2 qr3 qr4 qr5 qr6
            qr = r.model.getpos;
            qr1 = qr(1);
            qr2 = qr(2);
            qr3 = qr(3);
            qr4 = qr(4);
            qr5 = qr(5);
            qr6 = qr(6);

            global x y z
            x= 0;
            y=0;
            z=0;


        end

        % Value changed function: Joint1Slider_D
        function Joint1Slider_DValueChanged(app, event)
            global d;
            
             value = app.Joint1Slider_D.Value;

            global qd1 qd2 qd3 qd4 qd5
            qd1=deg2rad(value);

            d.model.animate([qd1 qd2 qd3 qd4 qd5 ]);
            t=d.model.fkine([qd1 qd2 qd3 qd4 qd5]);

%             set(app.XEditField_D,'Value',t(1,4));
%             set(app.YEditField_D,'Value',t(2,4));
%             set(app.ZEditField_D,'Value',t(3,4));
        end

        % Value changed function: Joint2Slider_D
        function Joint2Slider_DValueChanged(app, event)
global d;

         value = app.Joint2Slider_D.Value;


            global qd1 qd2 qd3 qd4 qd5 ;
            qd2=deg2rad(value);

            d.model.animate([qd1 qd2 qd3 qd4 qd5 ]);
            t=d.model.fkine([qd1 qd2 qd3 qd4 qd5]);
%             set(app.XEditField_D,'Value',t(1,4));
%             set(app.YEditField_D,'Value',t(2,4));
%             set(app.ZEditField_D,'Value',t(3,4));
 
        end

        % Value changed function: Joint3Slider_D
        function Joint3Slider_DValueChanged(app, event)
global d;

 value = app.Joint3Slider_D.Value;


            global qd1 qd2 qd3 qd4 qd5 ;
            qd3=deg2rad(value);

            d.model.animate([qd1 qd2 qd3 qd4 qd5 ]);
             t=d.model.fkine([qd1 qd2 qd3 qd4 qd5]);
%             set(app.XEditField_D,'Value',t(1,4));
%             set(app.YEditField_D,'Value',t(2,4));
%             set(app.ZEditField_D,'Value',t(3,4));
        end

        % Value changed function: Joint4Slider_D
        function Joint4Slider_DValueChanged(app, event)
global d;

 value = app.Joint4Slider_D.Value;


            global qd1 qd2 qd3 qd4 qd5 ;
            qd4=deg2rad(value);

            d.model.animate([qd1 qd2 qd3 qd4 qd5 ]);

           t=d.model.fkine([qd1 qd2 qd3 qd4 qd5]);
%             set(app.XEditField_D,'Value',t(1,4));
%             set(app.YEditField_D,'Value',t(2,4));
%             set(app.ZEditField_D,'Value',t(3,4));

        end

        % Value changed function: Joint5Slider_D
        function Joint5Slider_DValueChanged(app, event)
global d;

 value = app.Joint5Slider_D.Value;


            global qd1 qd2 qd3 qd4 qd5 ;
            qd5=deg2rad(value);

            d.model.animate([qd1 qd2 qd3 qd4 qd5 ]);

            t=d.model.fkine([qd1 qd2 qd3 qd4 qd5]);
%             set(app.XEditField_D,'Value',t(1,4));
%             set(app.YEditField_D,'Value',t(2,4));
%             set(app.ZEditField_D,'Value',t(3,4));

        end

        % Callback function
        function XEditField_DValueChanged(app, event)

        end

        % Callback function
        function YEditField_DValueChanged(app, event)
   
        end

        % Callback function
        function ZEditField_DValueChanged(app, event)
     
        end

        % Value changed function: Joint1Slider_R
        function Joint1Slider_RValueChanged(app, event)

            value = app.Joint1Slider_R.Value;
            global r;
            global qr1 qr2 qr3 qr4 qr5 qr6;
            qr1=deg2rad(value);

            r.model.animate([qr1 qr2 qr3 qr4 qr5 qr6]);


            t=r.model.fkine([qr1 qr2 qr3 qr4 qr5 qr6]);
%             set(app.XEditField_R,'Value',t(1,4));
%             set(app.YEditField_R,'Value',t(2,4));
%             set(app.ZEditField_R,'Value',t(3,4));


        end

        % Value changed function: Joint2Slider_R
        function Joint2Slider_RValueChanged(app, event)

            value = app.Joint2Slider_R.Value;
            global r;
            global qr1 qr2 qr3 qr4 qr5 qr6;
            qr2=deg2rad(value);

            r.model.animate([qr1 qr2 qr3 qr4 qr5 qr6]);

            t=r.model.fkine([qr1 qr2 qr3 qr4 qr5 qr6]);
%             set(app.XEditField_R,'Value',t(1,4));
%             set(app.YEditField_R,'Value',t(2,4));
%             set(app.ZEditField_R,'Value',t(3,4));      
        end

        % Value changed function: Joint3Slider_R
        function Joint3Slider_RValueChanged(app, event)


            value = app.Joint3Slider_R.Value;
            global r;
            global qr1 qr2 qr3 qr4 qr5 qr6;
            qr3=deg2rad(value);
 
            r.model.animate([qr1 qr2 qr3 qr4 qr5 qr6]);


            t=r.model.fkine([qr1 qr2 qr3 qr4 qr5 qr6]);
%             set(app.XEditField_R,'Value',t(1,4));
%             set(app.YEditField_R,'Value',t(2,4));
%             set(app.ZEditField_R,'Value',t(3,4));

                       
        end

        % Value changed function: Joint4Slider_R
        function Joint4Slider_RValueChanged(app, event)

           
            value = app.Joint4Slider_R.Value;
            global r;
            global qr1 qr2 qr3 qr4 qr5 qr6;
            qr4=deg2rad(value);
 
            r.model.animate([qr1 qr2 qr3 qr4 qr5 qr6]);


            t=r.model.fkine([qr1 qr2 qr3 qr4 qr5 qr6]);
%             set(app.XEditField_R,'Value',t(1,4));
%             set(app.YEditField_R,'Value',t(2,4));
%             set(app.ZEditField_R,'Value',t(3,4));

                   
        end

        % Value changed function: Joint5Slider_R
        function Joint5Slider_RValueChanged(app, event)
   

            value = app.Joint5Slider_R.Value;
            global r;
            global qr1 qr2 qr3 qr4 qr5 qr6;
            qr5=deg2rad(value);

            r.model.animate([qr1 qr2 qr3 qr4 qr5 qr6]);

            t=r.model.fkine([qr1 qr2 qr3 qr4 qr5 qr6]);
%             set(app.XEditField_R,'Value',t(1,4));
%             set(app.YEditField_R,'Value',t(2,4));
%             set(app.ZEditField_R,'Value',t(3,4));  
        end

        % Value changed function: Joint6Slider_R
        function Joint6Slider_RValueChanged(app, event)
            global r;
            value = app.Joint6Slider_R.Value;
            global qr1 qr2 qr3 qr4 qr5 qr6;
            qr6=deg2rad(value);

            r.model.animate([qr1 qr2 qr3 qr4 qr5 qr6]);

            t=r.model.fkine([qr1 qr2 qr3 qr4 qr5 qr6]);
%             set(app.XEditField_R,'Value',t(1,4));
%             set(app.YEditField_R,'Value',t(2,4));
%             set(app.ZEditField_R,'Value',t(3,4));
                  
        end

        % Callback function
        function XEditField_RValueChanged(app, event)

        end

        % Callback function
        function YEditField_RValueChanged(app, event)

        end

        % Callback function
        function ZEditField_RValueChanged(app, event)

        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            global d;
            changingValue = event.Value;
            
    k = 0.3;
    q = d.model.getpos;
    dt = 0.15;
        x = k*changingValue;    

        vel = [x, 0, 0, 0, 0, 0];

        epsilon = 0.1;
        J = d.model.jacob0(q);  % Get Jacobian at current joint state
        m = sqrt(det(J*J'));
        if m < epsilon  % If manipulability is less than given threshold
            lambda = 0.1;
        else
            lambda = 0;
        end
        invJ = inv(J'*J + lambda *eye(d.model.n))*J';        % DLS Inverse        
    
        qdot = invJ*vel';
        
        q = q + qdot'*dt;
    
        d.model.animate(q);  
    
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            
                        global d;
            changingValue = event.Value;
            
    k = 0.3;
    q = d.model.getpos;
    dt = 0.15;
        y = k*changingValue;    

        vel = [0, y, 0, 0, 0, 0];

        epsilon = 0.1;
        J = d.model.jacob0(q);  % Get Jacobian at current joint state
        m = sqrt(det(J*J'));
        if m < epsilon  % If manipulability is less than given threshold
            lambda = 0.1;
        else
            lambda = 0;
        end
        invJ = inv(J'*J + lambda *eye(d.model.n))*J';        % DLS Inverse        
    
        qdot = invJ*vel';
        
        q = q + qdot'*dt;
    
        d.model.animate(q);  
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
            
                        global d;
            changingValue = event.Value;
            
    k = 0.3;
    q = d.model.getpos;
    dt = 0.15;
        z = k*changingValue;    

        vel = [0, 0, z, 0, 0, 0];

        epsilon = 0.1;
        J = d.model.jacob0(q);  % Get Jacobian at current joint state
        m = sqrt(det(J*J'));
        if m < epsilon  % If manipulability is less than given threshold
            lambda = 0.1;
        else
            lambda = 0;
        end
        invJ = inv(J'*J + lambda *eye(d.model.n))*J';        % DLS Inverse        
    
        qdot = invJ*vel';
        
        q = q + qdot'*dt;
    
        d.model.animate(q);  
        end

        % Value changed function: XSlider_2
        function XSlider_2ValueChanged(app, event)
            value = app.XSlider_2.Value;
            
            global r;
            changingValue = event.Value;
            
    k = 0.3;
    q = r.model.getpos;
    dt = 0.15;
        x = k*changingValue;    

        vel = [x, 0, 0, 0, 0, 0];

        epsilon = 0.1;
        J = r.model.jacob0(q);  % Get Jacobian at current joint state
        m = sqrt(det(J*J'));
        if m < epsilon  % If manipulability is less than given threshold
            lambda = 0.1;
        else
            lambda = 0;
        end
        invJ = inv(J'*J + lambda *eye(r.model.n))*J';        % DLS Inverse        
    
        qdot = invJ*vel';
        
        q = q + qdot'*dt;
    
        r.model.animate(q);  

        end

        % Value changing function: YSlider_2
        function YSlider_2ValueChanging(app, event)
            value = app.YSlider_2.Value;
            global r;
            changingValue = event.Value;
            
    k = 0.3;
    q = r.model.getpos;
    dt = 0.15;
        y = k*changingValue;    

        vel = [0, y, 0, 0, 0, 0];

        epsilon = 0.1;
        J = r.model.jacob0(q);  % Get Jacobian at current joint state
        m = sqrt(det(J*J'));
        if m < epsilon  % If manipulability is less than given threshold
            lambda = 0.1;
        else
            lambda = 0;
        end
        invJ = inv(J'*J + lambda *eye(r.model.n))*J';        % DLS Inverse        
    
        qdot = invJ*vel';
        
        q = q + qdot'*dt;
    
        r.model.animate(q);  
        end

        % Value changed function: ZSlider_2
        function ZSlider_2ValueChanged(app, event)
            value = app.ZSlider_2.Value;
                        global r;
            changingValue = event.Value;
            
    k = 0.3;
    q = r.model.getpos;
    dt = 0.15;
        z = k*changingValue;    

        vel = [0, 0, z, 0, 0, 0];

        epsilon = 0.1;
        J = r.model.jacob0(q);  % Get Jacobian at current joint state
        m = sqrt(det(J*J'));
        if m < epsilon  % If manipulability is less than given threshold
            lambda = 0.1;
        else
            lambda = 0;
        end
        invJ = inv(J'*J + lambda *eye(r.model.n))*J';        % DLS Inverse        
    
        qdot = invJ*vel';
        
        q = q + qdot'*dt;
    
        r.model.animate(q);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider_D = uislider(app.UIFigure);
            app.Joint1Slider_D.Limits = [-180 180];
            app.Joint1Slider_D.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_DValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider_D = uislider(app.UIFigure);
            app.Joint2Slider_D.Limits = [-180 180];
            app.Joint2Slider_D.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_DValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider_D = uislider(app.UIFigure);
            app.Joint3Slider_D.Limits = [-180 180];
            app.Joint3Slider_D.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_DValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider_D = uislider(app.UIFigure);
            app.Joint4Slider_D.Limits = [-180 180];
            app.Joint4Slider_D.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_DValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider_D = uislider(app.UIFigure);
            app.Joint5Slider_D.Limits = [-180 180];
            app.Joint5Slider_D.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_DValueChanged, true);
            app.DobotLabel = uilabel(app.UIFigure);
            app.DobotLabel.Text = 'Dobot';
            app.RozumPulse75Label = uilabel(app.UIFigure);
            app.RozumPulse75Label.Text = 'RozumPulse75';
            app.Joint1Slider_2Label = uilabel(app.UIFigure);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider_R = uislider(app.UIFigure);
            app.Joint1Slider_R.Limits = [-180 180];
            app.Joint1Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_RValueChanged, true);
            app.Joint2Slider_2Label = uilabel(app.UIFigure);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider_R = uislider(app.UIFigure);
            app.Joint2Slider_R.Limits = [-180 180];
            app.Joint2Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_RValueChanged, true);
            app.Joint3Slider_2Label = uilabel(app.UIFigure);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider_R = uislider(app.UIFigure);
            app.Joint3Slider_R.Limits = [-180 180];
            app.Joint3Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_RValueChanged, true);
            app.Joint4Slider_2Label = uilabel(app.UIFigure);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider_R = uislider(app.UIFigure);
            app.Joint4Slider_R.Limits = [-180 180];
            app.Joint4Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_RValueChanged, true);
            app.Joint5Slider_2Label = uilabel(app.UIFigure);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.Joint5Slider_R = uislider(app.UIFigure);
            app.Joint5Slider_R.Limits = [-180 180];
            app.Joint5Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_RValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider_R = uislider(app.UIFigure);
            app.Joint6Slider_R.Limits = [-180 180];
            app.Joint6Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint6Slider_RValueChanged, true);
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-1 1];
            app.XSlider.MajorTicks = [-1 -0.6 -0.2 0.2 0.6 1];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-1 1];
            app.YSlider.MajorTicks = [-1 -0.6 -0.2 0.2 0.6 1];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-1 1];
            app.ZSlider.MajorTicks = [-1 -0.6 -0.2 0.2 0.6 1];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.XSlider_2Label = uilabel(app.UIFigure);
            app.XSlider_2Label.Text = 'X';
            app.XSlider_2 = uislider(app.UIFigure);
            app.XSlider_2.Limits = [-1 1];
            app.XSlider_2.MajorTicks = [-1 -0.6 -0.2 0.2 0.6 1];
            app.XSlider_2.ValueChangedFcn = createCallbackFcn(app, @XSlider_2ValueChanged, true);
            app.YSlider_2Label = uilabel(app.UIFigure);
            app.YSlider_2Label.Text = 'Y';
            app.YSlider_2 = uislider(app.UIFigure);
            app.YSlider_2.Limits = [-1 1];
            app.YSlider_2.MajorTicks = [-1 -0.6 -0.2 0.2 0.6 1];
            app.YSlider_2.ValueChangingFcn = createCallbackFcn(app, @YSlider_2ValueChanging, true);
            app.ZSlider_2Label = uilabel(app.UIFigure);
            app.ZSlider_2Label.Text = 'Z';
            app.ZSlider_2 = uislider(app.UIFigure);
            app.ZSlider_2.Limits = [-1 1];
            app.ZSlider_2.MajorTicks = [-1 -0.6 -0.2 0.2 0.6 1];
            app.ZSlider_2.ValueChangedFcn = createCallbackFcn(app, @ZSlider_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Revised

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end