classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ResumeButton         matlab.ui.control.Button
        Joint6Slider_R       matlab.ui.control.Slider
        Joint6SliderLabel    matlab.ui.control.Label
        ESTOPButton          matlab.ui.control.Button
        StartPackingButton   matlab.ui.control.Button
        Joint5Slider_R       matlab.ui.control.Slider
        Joint5Slider_2Label  matlab.ui.control.Label
        Joint4Slider_R       matlab.ui.control.Slider
        Joint4Slider_2Label  matlab.ui.control.Label
        Joint3Slider_R       matlab.ui.control.Slider
        Joint3Slider_2Label  matlab.ui.control.Label
        Joint2Slider_R       matlab.ui.control.Slider
        Joint2Slider_2Label  matlab.ui.control.Label
        Joint1Slider_R       matlab.ui.control.Slider
        Joint1Slider_2Label  matlab.ui.control.Label
        ZEditField_R         matlab.ui.control.NumericEditField
        ZEditField_2Label    matlab.ui.control.Label
        YEditField_R         matlab.ui.control.NumericEditField
        YEditField_2Label    matlab.ui.control.Label
        XEditField_R         matlab.ui.control.NumericEditField
        XEditField_2Label    matlab.ui.control.Label
        RozumPulse75Label    matlab.ui.control.Label
        ZEditField_D         matlab.ui.control.NumericEditField
        ZEditFieldLabel      matlab.ui.control.Label
        YEditField_D         matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        XEditField_D         matlab.ui.control.NumericEditField
        XEditFieldLabel      matlab.ui.control.Label
        DobotLabel           matlab.ui.control.Label
        Joint5Slider_D       matlab.ui.control.Slider
        Joint5SliderLabel    matlab.ui.control.Label
        Joint4Slider_D       matlab.ui.control.Slider
        Joint4SliderLabel    matlab.ui.control.Label
        Joint3Slider_D       matlab.ui.control.Slider
        Joint3SliderLabel    matlab.ui.control.Label
        Joint2Slider_D       matlab.ui.control.Slider
        Joint2SliderLabel    matlab.ui.control.Label
        Joint1Slider_D       matlab.ui.control.Slider
        Joint1SliderLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Slider_D
        function Joint1Slider_DValueChanged(app, event)
           d=Dobot();
               
            L(1) = Link('d',0.103+0.0362,    'a',0,      'alpha',-pi/2,  'offset',0, 'qlim',[deg2rad(-135),deg2rad(135)]);
            L(2) = Link('d',0,        'a',0.135,  'alpha',0,      'offset',-pi/2, 'qlim',[deg2rad(5),deg2rad(80)]);
            L(3) = Link('d',0,        'a',0.147,  'alpha',0,      'offset',0, 'qlim',[deg2rad(-5),deg2rad(85)]);
            L(4) = Link('d',0,        'a',0.06,      'alpha',pi/2,  'offset',-pi/2, 'qlim',[deg2rad(-180),deg2rad(180)]);
            L(5) = Link('d',-0.05,      'a',0,      'alpha',0,      'offset',pi, 'qlim',[deg2rad(-85),deg2rad(85)]);

            d = SerialLink([L1 L2 L3 L4 L5 ],'name','d');
            value = app.Joint1Slider_D.Value;
           global q1 q2 q3 q4 q5 ;
            q1=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           d.model.base = d.model.base*trotx(pi/2);
           d.plot([q1 q2 q3 q4 q5 ],'workspace',workspace,'scale',scale)
            t=d.fkine([q1 q2 q3 q4 q5 ]);
            set(app.XEditField_D,'Value',t(1,4));
            set(app.YEditField_D,'Value',t(2,4));
            set(app.ZEditField_D,'Value',t(3,4));

        end

        % Value changed function: Joint2Slider_D
        function Joint2Slider_DValueChanged(app, event)
            value = app.Joint2Slider_D.Value;
            d=Dobot();
               
            L(1) = Link('d',0.103+0.0362,    'a',0,      'alpha',-pi/2,  'offset',0, 'qlim',[deg2rad(-135),deg2rad(135)]);
            L(2) = Link('d',0,        'a',0.135,  'alpha',0,      'offset',-pi/2, 'qlim',[deg2rad(5),deg2rad(80)]);
            L(3) = Link('d',0,        'a',0.147,  'alpha',0,      'offset',0, 'qlim',[deg2rad(-5),deg2rad(85)]);
            L(4) = Link('d',0,        'a',0.06,      'alpha',pi/2,  'offset',-pi/2, 'qlim',[deg2rad(-180),deg2rad(180)]);
            L(5) = Link('d',-0.05,      'a',0,      'alpha',0,      'offset',pi, 'qlim',[deg2rad(-85),deg2rad(85)]);

            d = SerialLink([L1 L2 L3 L4 L5 ],'name','d');
           value = app.Joint2Slider_D.Value;
           global q1 q2 q3 q4 q5 ;
            q2=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           d.model.base = d.model.base*trotx(pi/2);
           d.plot([q1 q2 q3 q4 q5 ],'workspace',workspace,'scale',scale)
            t=d.fkine([q1 q2 q3 q4 q5 ]);
            set(app.XEditField_D,'Value',t(1,4));
            set(app.YEditField_D,'Value',t(2,4));
            set(app.ZEditField_D,'Value',t(3,4));

        end

        % Value changed function: Joint3Slider_D
        function Joint3Slider_DValueChanged(app, event)
            value = app.Joint3Slider_D.Value;
            d=Dobot()
               
            L(1) = Link('d',0.103+0.0362,    'a',0,      'alpha',-pi/2,  'offset',0, 'qlim',[deg2rad(-135),deg2rad(135)]);
            L(2) = Link('d',0,        'a',0.135,  'alpha',0,      'offset',-pi/2, 'qlim',[deg2rad(5),deg2rad(80)]);
            L(3) = Link('d',0,        'a',0.147,  'alpha',0,      'offset',0, 'qlim',[deg2rad(-5),deg2rad(85)]);
            L(4) = Link('d',0,        'a',0.06,      'alpha',pi/2,  'offset',-pi/2, 'qlim',[deg2rad(-180),deg2rad(180)]);
            L(5) = Link('d',-0.05,      'a',0,      'alpha',0,      'offset',pi, 'qlim',[deg2rad(-85),deg2rad(85)]);

            d = SerialLink([L1 L2 L3 L4 L5 ],'name','d');
           value = app.Joint3Slider_D.Value;
           global q1 q2 q3 q4 q5 ;
            q3=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           d.model.base = d.model.base*trotx(pi/2);
           d.plot([q1 q2 q3 q4 q5 ],'workspace',workspace,'scale',scale)
            t=d.fkine([q1 q2 q3 q4 q5 ]);
            set(app.XEditField_D,'Value',t(1,4));
            set(app.YEditField_D,'Value',t(2,4));
            set(app.ZEditField_D,'Value',t(3,4));

        end

        % Value changed function: Joint4Slider_D
        function Joint4Slider_DValueChanged(app, event)
            value = app.Joint4Slider_D.Value;
            d=Dobot()
               
            L(1) = Link('d',0.103+0.0362,    'a',0,      'alpha',-pi/2,  'offset',0, 'qlim',[deg2rad(-135),deg2rad(135)]);
            L(2) = Link('d',0,        'a',0.135,  'alpha',0,      'offset',-pi/2, 'qlim',[deg2rad(5),deg2rad(80)]);
            L(3) = Link('d',0,        'a',0.147,  'alpha',0,      'offset',0, 'qlim',[deg2rad(-5),deg2rad(85)]);
            L(4) = Link('d',0,        'a',0.06,      'alpha',pi/2,  'offset',-pi/2, 'qlim',[deg2rad(-180),deg2rad(180)]);
            L(5) = Link('d',-0.05,      'a',0,      'alpha',0,      'offset',pi, 'qlim',[deg2rad(-85),deg2rad(85)]);

            d = SerialLink([L1 L2 L3 L4 L5 ],'name','d');
           value = app.Joint4Slider_D.Value;
           global q1 q2 q3 q4 q5 ;
           q4=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           d.model.base = d.model.base*trotx(pi/2);
           d.plot([q1 q2 q3 q4 q5 ],'workspace',workspace,'scale',scale)
            t=d.fkine([q1 q2 q3 q4 q5 ]);
            set(app.XEditField_D,'Value',t(1,4));
            set(app.YEditField_D,'Value',t(2,4));
            set(app.ZEditField_D,'Value',t(3,4));

        end

        % Value changed function: Joint5Slider_D
        function Joint5Slider_DValueChanged(app, event)
            value = app.Joint5Slider_D.Value;
            d=Dobot()
               
            L(1) = Link('d',0.103+0.0362,    'a',0,      'alpha',-pi/2,  'offset',0, 'qlim',[deg2rad(-135),deg2rad(135)]);
            L(2) = Link('d',0,        'a',0.135,  'alpha',0,      'offset',-pi/2, 'qlim',[deg2rad(5),deg2rad(80)]);
            L(3) = Link('d',0,        'a',0.147,  'alpha',0,      'offset',0, 'qlim',[deg2rad(-5),deg2rad(85)]);
            L(4) = Link('d',0,        'a',0.06,      'alpha',pi/2,  'offset',-pi/2, 'qlim',[deg2rad(-180),deg2rad(180)]);
            L(5) = Link('d',-0.05,      'a',0,      'alpha',0,      'offset',pi, 'qlim',[deg2rad(-85),deg2rad(85)]);

            d = SerialLink([L1 L2 L3 L4 L5 ],'name','d');
           value = app.Joint5Slider_D.Value;
           global q1 q2 q3 q4 q5 ;
           q5=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           d.model.base = d.model.base*trotx(pi/2);
           d.plot([q1 q2 q3 q4 q5 ],'workspace',workspace,'scale',scale)
            t=d.fkine([q1 q2 q3 q4 q5 ]);
            set(app.XEditField_D,'Value',t(1,4));
            set(app.YEditField_D,'Value',t(2,4));
            set(app.ZEditField_D,'Value',t(3,4)); 
        end

        % Value changed function: XEditField_D
        function XEditField_DValueChanged(app, event)
            value = app.XEditField_D.Value;
            d=Dobot()
               
            L(1) = Link('d',0.103+0.0362,    'a',0,      'alpha',-pi/2,  'offset',0, 'qlim',[deg2rad(-135),deg2rad(135)]);
            L(2) = Link('d',0,        'a',0.135,  'alpha',0,      'offset',-pi/2, 'qlim',[deg2rad(5),deg2rad(80)]);
            L(3) = Link('d',0,        'a',0.147,  'alpha',0,      'offset',0, 'qlim',[deg2rad(-5),deg2rad(85)]);
            L(4) = Link('d',0,        'a',0.06,      'alpha',pi/2,  'offset',-pi/2, 'qlim',[deg2rad(-180),deg2rad(180)]);
            L(5) = Link('d',-0.05,      'a',0,      'alpha',0,      'offset',pi, 'qlim',[deg2rad(-85),deg2rad(85)]);

            d = SerialLink([L1 L2 L3 L4 L5 ],'name','d');
            global x y z;
            x= value;
            T=transl([x,y,z]);
      qMatrix = d.ikine6s(T,'run');
      d.base = transl(0,0.04,-1.0);  

        end

        % Value changed function: YEditField_D
        function YEditField_DValueChanged(app, event)
            value = app.YEditField_D.Value;
            d=Dobot();

            L(1) = Link('d',0.103+0.0362,    'a',0,      'alpha',-pi/2,  'offset',0, 'qlim',[deg2rad(-135),deg2rad(135)]);
            L(2) = Link('d',0,        'a',0.135,  'alpha',0,      'offset',-pi/2, 'qlim',[deg2rad(5),deg2rad(80)]);
            L(3) = Link('d',0,        'a',0.147,  'alpha',0,      'offset',0, 'qlim',[deg2rad(-5),deg2rad(85)]);
            L(4) = Link('d',0,        'a',0.06,      'alpha',pi/2,  'offset',-pi/2, 'qlim',[deg2rad(-180),deg2rad(180)]);
            L(5) = Link('d',-0.05,      'a',0,      'alpha',0,      'offset',pi, 'qlim',[deg2rad(-85),deg2rad(85)]);

            d = SerialLink([L1 L2 L3 L4 L5 ],'name','d');
            global x y z;
            y= value;
            T=transl([x,y,z]);
      qMatrix = d.ikine6s(T,'run');
      d.base = transl(0,0.04,-1.0); 
   
        end

        % Value changed function: ZEditField_D
        function ZEditField_DValueChanged(app, event)
            value = app.ZEditField_D.Value;
            d=Dobot();
               
            L(1) = Link('d',0.103+0.0362,    'a',0,      'alpha',-pi/2,  'offset',0, 'qlim',[deg2rad(-135),deg2rad(135)]);
            L(2) = Link('d',0,        'a',0.135,  'alpha',0,      'offset',-pi/2, 'qlim',[deg2rad(5),deg2rad(80)]);
            L(3) = Link('d',0,        'a',0.147,  'alpha',0,      'offset',0, 'qlim',[deg2rad(-5),deg2rad(85)]);
            L(4) = Link('d',0,        'a',0.06,      'alpha',pi/2,  'offset',-pi/2, 'qlim',[deg2rad(-180),deg2rad(180)]);
            L(5) = Link('d',-0.05,      'a',0,      'alpha',0,      'offset',pi, 'qlim',[deg2rad(-85),deg2rad(85)]);

            d = SerialLink([L1 L2 L3 L4 L5 ],'name','d');
            global x y z;
            z= value;
            T=transl([x,y,z]);
      qMatrix = d.ikine6s(T,'run');
      d.base = transl(0,0.04,-1.0);       
        end

        % Value changed function: Joint1Slider_R
        function Joint1Slider_RValueChanged(app, event)
            value = app.Joint1Slider_R.Value;
            r = RozumPulse75()
            L1 = Link('d',232.5/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L2 = Link('d',120.5/1000,'a',375/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',pi/2); 

            L3 = Link('d',-97.5/1000,'a',295/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            
            L4 = Link('d',97.5/1000,'a',0,'alpha',-pi/2,'qlim',deg2rad([-180 180]), 'offset',-pi/2); 
            L5 = Link('d',171/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L6 = Link('d',122.6/1000,'a',0,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            r = SerialLink([L1 L2 L3 L4 L5 L6],'name','r');
           value = app.Joint1Slider_R;
           global q1 q2 q3 q4 q5 q6;
           q1=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           r.model.base = r.model.base*transl(0,-0.8,0.5+0.35);
           r.plot([q1 q2 q3 q4 q5 q6],'workspace',workspace,'scale',scale)
            t=r.fkine([q1 q2 q3 q4 q5 q6]);
            set(app.XEditField_R,'Value',t(1,4));
            set(app.YEditField_R,'Value',t(2,4));
            set(app.ZEditField_R,'Value',t(3,4));
           
        end

        % Value changed function: Joint2Slider_R
        function Joint2Slider_RValueChanged(app, event)
            value = app.Joint2Slider_R.Value;
             r = RozumPulse75()
            L1 = Link('d',232.5/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L2 = Link('d',120.5/1000,'a',375/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',pi/2); 

            L3 = Link('d',-97.5/1000,'a',295/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            
            L4 = Link('d',97.5/1000,'a',0,'alpha',-pi/2,'qlim',deg2rad([-180 180]), 'offset',-pi/2); 
            L5 = Link('d',171/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L6 = Link('d',122.6/1000,'a',0,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            r = SerialLink([L1 L2 L3 L4 L5 L6],'name','r');
           value = app.Joint2Slider_R;
           global q1 q2 q3 q4 q5 q6;
           q2=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           r.model.base = r.model.base*transl(0,-0.8,0.5+0.35);
           r.plot([q1 q2 q3 q4 q5 q6],'workspace',workspace,'scale',scale)
            t=r.fkine([q1 q2 q3 q4 q5 q6]);
            set(app.XEditField_R,'Value',t(1,4));
            set(app.YEditField_R,'Value',t(2,4));
            set(app.ZEditField_R,'Value',t(3,4));
                   
        end

        % Value changed function: Joint3Slider_R
        function Joint3Slider_RValueChanged(app, event)
            value = app.Joint3Slider_R.Value;
             r = RozumPulse75()
            L1 = Link('d',232.5/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L2 = Link('d',120.5/1000,'a',375/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',pi/2); 

            L3 = Link('d',-97.5/1000,'a',295/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            
            L4 = Link('d',97.5/1000,'a',0,'alpha',-pi/2,'qlim',deg2rad([-180 180]), 'offset',-pi/2); 
            L5 = Link('d',171/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L6 = Link('d',122.6/1000,'a',0,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            r = SerialLink([L1 L2 L3 L4 L5 L6],'name','r');
           value = app.Joint3Slider_R;
           global q1 q2 q3 q4 q5 q6;
           q3=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           r.model.base = r.model.base*transl(0,-0.8,0.5+0.35);
           r.plot([q1 q2 q3 q4 q5 q6],'workspace',workspace,'scale',scale)
            t=r.fkine([q1 q2 q3 q4 q5 q6]);
            set(app.XEditField_R,'Value',t(1,4));
            set(app.YEditField_R,'Value',t(2,4));
            set(app.ZEditField_R,'Value',t(3,4));
                       
        end

        % Value changed function: Joint4Slider_R
        function Joint4Slider_RValueChanged(app, event)
            value = app.Joint4Slider_R.Value;
            r = RozumPulse75()
            L1 = Link('d',232.5/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L2 = Link('d',120.5/1000,'a',375/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',pi/2); 

            L3 = Link('d',-97.5/1000,'a',295/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            
            L4 = Link('d',97.5/1000,'a',0,'alpha',-pi/2,'qlim',deg2rad([-180 180]), 'offset',-pi/2); 
            L5 = Link('d',171/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L6 = Link('d',122.6/1000,'a',0,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            r = SerialLink([L1 L2 L3 L4 L5 L6],'name','r');
           value = app.Joint4Slider_R;
           global q1 q2 q3 q4 q5 q6;
           q4=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           r.model.base = r.model.base*transl(0,-0.8,0.5+0.35);
           r.plot([q1 q2 q3 q4 q5 q6],'workspace',workspace,'scale',scale)
            t=r.fkine([q1 q2 q3 q4 q5 q6]);
            set(app.XEditField_R,'Value',t(1,4));
            set(app.YEditField_R,'Value',t(2,4));
            set(app.ZEditField_R,'Value',t(3,4));
                   
        end

        % Value changed function: Joint5Slider_R
        function Joint5Slider_RValueChanged(app, event)
            value = app.Joint5Slider_R.Value;
           r = RozumPulse75()
            L1 = Link('d',232.5/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L2 = Link('d',120.5/1000,'a',375/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',pi/2); 

            L3 = Link('d',-97.5/1000,'a',295/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            
            L4 = Link('d',97.5/1000,'a',0,'alpha',-pi/2,'qlim',deg2rad([-180 180]), 'offset',-pi/2); 
            L5 = Link('d',171/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L6 = Link('d',122.6/1000,'a',0,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            r = SerialLink([L1 L2 L3 L4 L5 L6],'name','r');
           value = app.Joint5Slider_R;
           global q1 q2 q3 q4 q5 q6;
           q5=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           r.model.base = r.model.base*transl(0,-0.8,0.5+0.35);
           r.plot([q1 q2 q3 q4 q5 q6],'workspace',workspace,'scale',scale)
            t=r.fkine([q1 q2 q3 q4 q5 q6]);
            set(app.XEditField_R,'Value',t(1,4));
            set(app.YEditField_R,'Value',t(2,4));
            set(app.ZEditField_R,'Value',t(3,4));
              
        end

        % Value changed function: Joint6Slider_R
        function Joint6Slider_RValueChanged(app, event)
            value = app.Joint6Slider_R.Value;
          r = RozumPulse75()
            L1 = Link('d',232.5/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L2 = Link('d',120.5/1000,'a',375/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',pi/2); 

            L3 = Link('d',-97.5/1000,'a',295/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            
            L4 = Link('d',97.5/1000,'a',0,'alpha',-pi/2,'qlim',deg2rad([-180 180]), 'offset',-pi/2); 
            L5 = Link('d',171/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L6 = Link('d',122.6/1000,'a',0,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            r = SerialLink([L1 L2 L3 L4 L5 L6],'name','r');
           value = app.Joint6Slider_R;
           global q1 q2 q3 q4 q5 q6;
           q6=deg2rad(value);
           workspace=[-5 5 -5 5 0 5]./5;  
           scale=0.5;
           r.model.base = r.model.base*transl(0,-0.8,0.5+0.35);
           r.plot([q1 q2 q3 q4 q5 q6],'workspace',workspace,'scale',scale)
            t=r.fkine([q1 q2 q3 q4 q5 q6]);
            set(app.XEditField_R,'Value',t(1,4));
            set(app.YEditField_R,'Value',t(2,4));
            set(app.ZEditField_R,'Value',t(3,4));
                  
        end

        % Value changed function: XEditField_R
        function XEditField_RValueChanged(app, event)
            value = app.XEditField_R.Value;
            r = RozumPulse75()
            L1 = Link('d',232.5/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L2 = Link('d',120.5/1000,'a',375/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',pi/2); 

            L3 = Link('d',-97.5/1000,'a',295/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            
            L4 = Link('d',97.5/1000,'a',0,'alpha',-pi/2,'qlim',deg2rad([-180 180]), 'offset',-pi/2); 
            L5 = Link('d',171/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L6 = Link('d',122.6/1000,'a',0,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            r = SerialLink([L1 L2 L3 L4 L5 L6],'name','r'); 
            global x y z;
            x= value;
            T=transl([x,y,z]);
      qMatrix = r.ikine6s(T,'run');
      r.base = transl(0,-0.8,0.5+0.35); 

        end

        % Value changed function: YEditField_R
        function YEditField_RValueChanged(app, event)
            value = app.YEditField_R.Value;
            r = RozumPulse75()
            L1 = Link('d',232.5/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L2 = Link('d',120.5/1000,'a',375/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',pi/2); 

            L3 = Link('d',-97.5/1000,'a',295/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            
            L4 = Link('d',97.5/1000,'a',0,'alpha',-pi/2,'qlim',deg2rad([-180 180]), 'offset',-pi/2); 
            L5 = Link('d',171/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L6 = Link('d',122.6/1000,'a',0,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            r = SerialLink([L1 L2 L3 L4 L5 L6],'name','r'); 
            global x y z;
            y= value;
            T=transl([x,y,z]);
      qMatrix = r.ikine6s(T,'run');
      r.base = transl(0,-0.8,0.5+0.35);  
        end

        % Value changed function: ZEditField_R
        function ZEditField_RValueChanged(app, event)
            value = app.ZEditField_R.Value;
            r = RozumPulse75()
            L1 = Link('d',232.5/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L2 = Link('d',120.5/1000,'a',375/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',pi/2); 

            L3 = Link('d',-97.5/1000,'a',295/1000,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            
            L4 = Link('d',97.5/1000,'a',0,'alpha',-pi/2,'qlim',deg2rad([-180 180]), 'offset',-pi/2); 
            L5 = Link('d',171/1000,'a',0,'alpha',pi/2,'qlim',deg2rad([-180 180]), 'offset',0); 
            L6 = Link('d',122.6/1000,'a',0,'alpha',0,'qlim',deg2rad([-180 180]), 'offset',0); 
            r = SerialLink([L1 L2 L3 L4 L5 L6],'name','r'); 
            global x y z;
            z= value;
            T=transl([x,y,z]);
      qMatrix = r.ikine6s(T,'run');
      r.base = transl(0,-0.8,0.5+0.35);  
        end

        % Button pushed function: StartPackingButton
        function StartPackingButtonPushed(app, event)
            % waiting you finsh pick up fuction
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            uiwait;
            disp('E-STOP')
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            uiwait;
            disp('Resume')   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider_D = uislider(app.UIFigure);
            app.Joint1Slider_D.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_DValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider_D = uislider(app.UIFigure);
            app.Joint2Slider_D.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_DValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider_D = uislider(app.UIFigure);
            app.Joint3Slider_D.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_DValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider_D = uislider(app.UIFigure);
            app.Joint4Slider_D.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_DValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider_D = uislider(app.UIFigure);
            app.Joint5Slider_D.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_DValueChanged, true);
            app.DobotLabel = uilabel(app.UIFigure);
            app.DobotLabel.Text = 'Dobot';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField_D = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_D.ValueChangedFcn = createCallbackFcn(app, @XEditField_DValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField_D = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_D.ValueChangedFcn = createCallbackFcn(app, @YEditField_DValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField_D = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_D.ValueChangedFcn = createCallbackFcn(app, @ZEditField_DValueChanged, true);
            app.RozumPulse75Label = uilabel(app.UIFigure);
            app.RozumPulse75Label.Text = 'RozumPulse75';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_R = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_R.ValueChangedFcn = createCallbackFcn(app, @XEditField_RValueChanged, true);
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_R = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_R.ValueChangedFcn = createCallbackFcn(app, @YEditField_RValueChanged, true);
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_R = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_R.ValueChangedFcn = createCallbackFcn(app, @ZEditField_RValueChanged, true);
            app.Joint1Slider_2Label = uilabel(app.UIFigure);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider_R = uislider(app.UIFigure);
            app.Joint1Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_RValueChanged, true);
            app.Joint2Slider_2Label = uilabel(app.UIFigure);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider_R = uislider(app.UIFigure);
            app.Joint2Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_RValueChanged, true);
            app.Joint3Slider_2Label = uilabel(app.UIFigure);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider_R = uislider(app.UIFigure);
            app.Joint3Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_RValueChanged, true);
            app.Joint4Slider_2Label = uilabel(app.UIFigure);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider_R = uislider(app.UIFigure);
            app.Joint4Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_RValueChanged, true);
            app.Joint5Slider_2Label = uilabel(app.UIFigure);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.Joint5Slider_R = uislider(app.UIFigure);
            app.Joint5Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_RValueChanged, true);
            app.StartPackingButton = uibutton(app.UIFigure, 'push');
            app.StartPackingButton.ButtonPushedFcn = createCallbackFcn(app, @StartPackingButtonPushed, true);
            app.StartPackingButton.Text = 'Start Packing';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider_R = uislider(app.UIFigure);
            app.Joint6Slider_R.ValueChangedFcn = createCallbackFcn(app, @Joint6Slider_RValueChanged, true);
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end